classdef guiApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ScaleRatioEditField             matlab.ui.control.NumericEditField
        ScaleRatioEditFieldLabel        matlab.ui.control.Label
        Rotate180Button                 matlab.ui.control.Button
        Rotate90CounterclockwiseButton  matlab.ui.control.Button
        Rotate90ClockwiseButton         matlab.ui.control.Button
        ReflectionCheckBox              matlab.ui.control.CheckBox
        ReflectionLabel                 matlab.ui.control.Label
        ScalingCheckBox                 matlab.ui.control.CheckBox
        ScalingLabel                    matlab.ui.control.Label
        RotateAngleEditField            matlab.ui.control.NumericEditField
        RotateAngleEditFieldLabel       matlab.ui.control.Label
        ApplyTransformationsButton      matlab.ui.control.Button
        RotateCheckBox                  matlab.ui.control.CheckBox
        RotateLabel                     matlab.ui.control.Label
        TranslateCheckBox               matlab.ui.control.CheckBox
        TranslateLabel                  matlab.ui.control.Label
        ResetTransormationsImageButton  matlab.ui.control.Button
        InputYTranslateEditField        matlab.ui.control.NumericEditField
        InputYEditFieldLabel            matlab.ui.control.Label
        InputXTranslateEditField        matlab.ui.control.NumericEditField
        InputXEditFieldLabel            matlab.ui.control.Label
        LoadImageButton                 matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        image 
        transformedImage
        translateImageActivated = false
        rotateImageActivated = false
        scalingImageActivated = false
        transformCounter  = 0
     
    end
    
    
    methods (Access = public)
        
        function results = func(app)
            
        end
        
    function displayImageInNewWindow(app, image)
    % new figure window for displaying the image
    fig = figure;


    if isvalid(fig)
        % axes in the new figure
        ax = axes(fig);
        axis off

        imshow(image, 'Parent', ax);

        title(ax, 'Translated Image in New Window');
        set(fig, 'Position', [100, 100, 600, 400]);  % window size
    else
        disp('Error: Could not create a new figure window.');
    end
end
    end


    methods (Access = public)
    
    function applyRotation(app)
    % Get the rotation angle from user input
    angle = app.RotateAngleEditField.Value;

    % Rotate the transformed image or the original image if not yet transformed
    if isempty(app.transformedImage)
        app.transformedImage = imrotate(app.image, angle, 'bilinear', 'crop');
    else
        app.transformedImage = imrotate(app.transformedImage, angle, 'bilinear', 'crop');
    end

    % Show the rotated image in UIAxes_2
    imshow(app.transformedImage, 'Parent', app.UIAxes_2);
    title(app.UIAxes_2, ['Rotated ', num2str(angle), '°']);
     end

        
    function applyTranslate(app)
    value = app.TranslateCheckBox.Value;


    % placeholder values
    if isempty(app.InputXTranslateEditField.Value) || isempty(app.InputYTranslateEditField.Value)
        app.InputXTranslateEditField.Value = 0;
        app.InputYTranslateEditField.Value = 0;
    end

    % translation values
    tx = app.InputXTranslateEditField.Value;
    ty = app.InputYTranslateEditField.Value;

    % printing input tx and ty values to command window
    disp(['Translation in X: ', num2str(tx)]);
    disp(['Translation in Y: ', num2str(ty)]);

    %  numerical input checking
    if isnan(tx) || isnan(ty)
        uialert(app.UIFigure, 'Please enter valid numbers for translation.', 'Invalid Input');
        app.TranslateButton.Enable = 'on';
        return;
    end

    % size of the original image
    [rows, cols, ~] = size(app.image);

    %  affine transformation matrix
    tform = affine2d([1 0 0; 0 1 0; tx ty 1]);

    % transformation to the image using imwarp
    outputView = imref2d([rows, cols]);
    
    % translation and maintain the original image size
    app.transformedImage = imwarp(app.image, tform, 'OutputView', outputView);  

    % displaying the transformed image in the UIAxes_2
    title(app.UIAxes_2, 'Transformed Image');
    imshow(app.transformedImage, 'Parent', app.UIAxes_2);

     
    % title(app.UIAxes, 'Translated Image');
    % app.UIAxes.XLabel.String = '';
    % app.UIAxes.YLabel.String = '';


    % HELPFUL: (if adding one by one transformation showing later)
    % Create a new figure window for the translated image
    % figureHandleTranslated = figure('Name', 'Translated Image', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);
    % imshow(app.transformedImage);  % Display the transformed image in the new window
    % title('Translated Image in New Window');
    % set(figureHandleTranslated, 'Position', [200, 200, 900, 700]);  % Set the size of the new window for the translated image


    % Re-enable buttons after translation
    % app.LoadImageButton.Enable = 'on';
    % app.Rotate180Button.Enable = 'on';
    % app.ResizeButton.Enable = 'on';

         
    end
    
    % this is the scaling function
    function applyScaling(app)
        % this is for getting the scale ratio from the user
        scale = app.ScaleRatioEditField.Value;

        % this checks if user entered a valid value
        if all([~isnumeric(scale), scale <= 0, isnan(scale)])
            uialert(app.UIFigure, 'Enter a positive number for the scale ratio.', 'Invalid Input');
            return;
        end

        % this is to apply scaling
        if isempty(app.transformedImage)
            app.transformedImage = imresize(app.image, scale);
        else
            app.transformedImage = imresize(app.transformedImage, scale);
        end

        % this is to show the image when scale has been applied
        imshow(app.transformedImage, 'Parent', app.UIAxes_2);
        title(app.UIAxes_2, ['Image Scaled By ', num2str(scale)]);
    end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: LoadImageButton, UIFigure
        function LoadImageButtonPushed(app, event)
            
            % File dialog input from user
            
            % direct to starting folder
            startingFolder = pwd;
            if ~isfolder(startingFolder)

                startingFolder = pwd
            end
            % Creating file path 
            defaultFileName = fullfile(startingFolder,'*.*')
            % declare valid extensions 

            [baseFileName, folder] = uigetfile({'*.png;*.jpg;*.gif', 'Image Files (*.png, *.jpg, *.gif)'}, ...
            defaultFileName, 'Select Image file');            % dialog canceled 
            if baseFileName == 0
                return;
            end

            %  user does not input invalid image file %


            fullFileName = fullfile(folder,baseFileName)
            app.image = imread(fullFileName)
            % displaying the image in UIAxes
          
            imshow(app.image, 'Parent', app.UIAxes);
            app.image = im2double(app.image);
            [rows, cols, ~] = size(app.image);
           
            axis off


            title(app.UIAxes, 'Loaded Image');
            % imshow(app.image)
            % enable buttons after loading\
            app.ApplyTransformationsButton.Enable = 'on'
            app.RotateCheckBox.Enable = 'on'
            app.ScalingCheckBox.Enable = 'on'
            app.ReflectionCheckBox.Enable = 'on'
            app.TranslateCheckBox.Enable = 'on'

        end

        % Button pushed function: ResetTransormationsImageButton
        function ResetTransormationsImageButtonPushed(app, event)
             if isempty(app.image)
                 uialert(app.UIFigure, 'No image to reset.', 'Error');
             return;
             end

        % Clear UIAxes_2 (transformed image preview)
         cla(app.UIAxes_2);                      % Clear axes
         title(app.UIAxes_2, '');                % Clear title
         axis(app.UIAxes_2, 'off');              % Hide axes visuals

        % Re-display original image in UIAxes (original image view)
        imshow(app.image, 'Parent', app.UIAxes);
        title(app.UIAxes, 'Original Image');

        % Reset transformation variables
        app.transformedImage = [];              % Clear any transformed image
        app.translateImageActivated = false;
        app.rotateImageActivated = false;
        app.transformCounter = 0;

        % Reset input fields and checkboxes
        app.TranslateCheckBox.Value = false;
        app.RotateCheckBox.Value = false;
        app.InputXTranslateEditField.Value = 0;
        app.InputYTranslateEditField.Value = 0;
        app.RotateAngleEditField.Value = 0;           


            % Implement buttoon logic

        end

        % Value changed function: TranslateCheckBox
        function TranslateCheckBoxValueChanged(app, event)
    value = app.TranslateCheckBox.Value;
    app.translateImageActivated = value
    
    if value == 1
        %  button is toggled on
        app.InputXTranslateEditField.Enable = 'on'
        app.InputYTranslateEditField.Enable = 'on'
        app.transformCounter = app.transformCounter + 1
    else
        app.InputXTranslateEditField.Enable = 'off'
        app.InputYTranslateEditField.Enable = 'off'
    end


    % re-enable buttons after translation
    % app.LoadImageButton.Enable = 'on';
    % app.Rotate180Button.Enable = 'on';
    % app.ResizeButton.Enable = 'on';
        end

        % Value changed function: RotateCheckBox
        function RotateCheckBoxValueChanged(app, event)
            value = app.RotateCheckBox.Value
            app.rotateImageActivated = value
            if value == 1
                if app.transformCounter >= 3
                    % return error message and do not allow to proceed % 
                end
                % Update global transform counter %
                app.transformCounter + 1
                app.RotateAngleEditField.Enable = 'on'
                app.Rotate90ClockwiseButton.Enable = 'on'
                app.Rotate180Button.Enable = 'on'
                app.Rotate90CounterclockwiseButton.Enable = 'on'
            else
                app.RotateAngleEditField.Enable = 'off'
                app.Rotate90ClockwiseButton.Enable = 'off'
                app.Rotate180Button.Enable = 'off'
                app.Rotate90CounterclockwiseButton.Enable = 'off'
                app.transformCounter - 1
            end


        end

        % Button pushed function: ApplyTransformationsButton
        function ApplyTransformationsButtonPushed(app, event)
            % Check Values of all for 4 check boxes and apply
            % transformations as needed
            % Reflection, Scaling, Rotation, Translation

            if app.rotateImageActivated
                % do rotation
                if isempty(app.image)
                uialert(app.UIFigure, 'Please load an image first.', 'No Image');
                return;
                end

                applyRotation(app);

            end


            if app.translateImageActivated 
                % do translation
                applyTranslate(app)
            end

            if app.scalingImageActivated
                % this is to do scaling
                applyScaling(app);
            end 


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: Rotate90ClockwiseButton
        function Rotate90ClockwiseButtonPushed(app, event)
         if isempty(app.image)
              uialert(app.UIFigure, 'Please load an image first.', 'No Image');
             return;
         end

            app.transformedImage = imrotate(app.image, -90, 'bilinear', 'crop');
            imshow(app.transformedImage, 'Parent', app.UIAxes_2);
            
        end

        % Button pushed function: Rotate90CounterclockwiseButton
        function Rotate90CounterclockwiseButtonPushed(app, event)
             if isempty(app.image)
                 uialert(app.UIFigure, 'Please load an image first.', 'No Image');
                 return;
             end

            app.transformedImage = imrotate(app.image, 90, 'bilinear', 'crop');
            imshow(app.transformedImage, 'Parent', app.UIAxes_2);
            
        end

        % Button pushed function: Rotate180Button
        function Rotate180ButtonPushed(app, event)
             if isempty(app.image)
                uialert(app.UIFigure, 'Please load an image first.', 'No Image');
             return;
             end

            app.transformedImage = imrotate(app.image, 180, 'bilinear', 'crop');
            imshow(app.transformedImage, 'Parent', app.UIAxes_2);
            
        end

        % Value changed function: ScaleRatioEditField
        function ScaleRatioEditFieldValueChanged(app, event)
        % TYPO edit out % 
        end

        % Value changed function: ScalingCheckBox
        function ScalingCheckBoxValueChanged(app, event)
            value = app.ScalingCheckBox.Value;
            if value == 1
                app.ScaleRatioEditField.Enable = 'on'
                app.transformCounter = app.transformCounter + 1

            else
                app.ScalingRatioEditField.Enable = 'off'
                app.transformCounter = app.transformCounter - 1
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            app.UIAxes_2.Visible = 'off';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.InputXEditFieldLabel = uilabel(app.UIFigure);
            app.InputXEditFieldLabel.Enable = 'off';
            app.InputXEditFieldLabel.Text = 'Input X';
            app.InputXTranslateEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputXTranslateEditField.Enable = 'off';
            app.InputYEditFieldLabel = uilabel(app.UIFigure);
            app.InputYEditFieldLabel.Enable = 'off';
            app.InputYEditFieldLabel.Text = 'Input Y';
            app.InputYTranslateEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputYTranslateEditField.Enable = 'off';
            app.ResetTransormationsImageButton = uibutton(app.UIFigure, 'push');
            app.ResetTransormationsImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTransormationsImageButtonPushed, true);
            app.ResetTransormationsImageButton.Text = 'Reset Transormations Image';
            app.TranslateLabel = uilabel(app.UIFigure);
            app.TranslateLabel.Text = 'Translate';
            app.TranslateCheckBox = uicheckbox(app.UIFigure);
            app.TranslateCheckBox.ValueChangedFcn = createCallbackFcn(app, @TranslateCheckBoxValueChanged, true);
            app.TranslateCheckBox.Enable = 'off';
            app.RotateLabel = uilabel(app.UIFigure);
            app.RotateLabel.Text = 'Rotate';
            app.RotateCheckBox = uicheckbox(app.UIFigure);
            app.RotateCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotateCheckBoxValueChanged, true);
            app.RotateCheckBox.Enable = 'off';
            app.ApplyTransformationsButton = uibutton(app.UIFigure, 'push');
            app.ApplyTransformationsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTransformationsButtonPushed, true);
            app.ApplyTransformationsButton.Enable = 'off';
            app.ApplyTransformationsButton.Text = 'Apply Transformations';
            app.RotateAngleEditFieldLabel = uilabel(app.UIFigure);
            app.RotateAngleEditFieldLabel.Enable = 'off';
            app.RotateAngleEditFieldLabel.Text = 'Rotate Angle';
            app.RotateAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotateAngleEditField.Enable = 'off';
            app.ScalingLabel = uilabel(app.UIFigure);
            app.ScalingLabel.Text = 'Scaling';
            app.ScalingCheckBox = uicheckbox(app.UIFigure);
            app.ScalingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScalingCheckBoxValueChanged, true);
            app.ScalingCheckBox.Enable = 'off';
            app.ReflectionLabel = uilabel(app.UIFigure);
            app.ReflectionLabel.Text = 'Reflection';
            app.ReflectionCheckBox = uicheckbox(app.UIFigure);
            app.ReflectionCheckBox.Enable = 'off';
            app.Rotate90ClockwiseButton = uibutton(app.UIFigure, 'push');
            app.Rotate90ClockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @Rotate90ClockwiseButtonPushed, true);
            app.Rotate90ClockwiseButton.Icon = fullfile(pathToMLAPP, 'rotate-right.png');
            app.Rotate90ClockwiseButton.Enable = 'off';
            app.Rotate90ClockwiseButton.Text = 'Rotate 90° ';
            app.Rotate90CounterclockwiseButton = uibutton(app.UIFigure, 'push');
            app.Rotate90CounterclockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @Rotate90CounterclockwiseButtonPushed, true);
            app.Rotate90CounterclockwiseButton.Icon = fullfile(pathToMLAPP, 'rotate-left.png');
            app.Rotate90CounterclockwiseButton.Enable = 'off';
            app.Rotate90CounterclockwiseButton.Text = 'Rotate 90°';
            app.Rotate180Button = uibutton(app.UIFigure, 'push');
            app.Rotate180Button.ButtonPushedFcn = createCallbackFcn(app, @Rotate180ButtonPushed, true);
            app.Rotate180Button.Enable = 'off';
            app.Rotate180Button.Text = 'Rotate 180°';
            app.ScaleRatioEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleRatioEditFieldLabel.Enable = 'off';
            app.ScaleRatioEditFieldLabel.Text = 'Scale Ratio';
            app.ScaleRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleRatioEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleRatioEditFieldValueChanged, true);
            app.ScaleRatioEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end